classdef appMPVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        DadosPanel         matlab.ui.container.Panel
        ftyEditFieldLabel  matlab.ui.control.Label
        Ef                 matlab.ui.control.EditField
        aEditFieldLabel    matlab.ui.control.Label
        Ea                 matlab.ui.control.NumericEditField
        bEditFieldLabel    matlab.ui.control.Label
        Eb                 matlab.ui.control.NumericEditField
        nEditFieldLabel    matlab.ui.control.Label
        En                 matlab.ui.control.NumericEditField
        y0EditFieldLabel   matlab.ui.control.Label
        Ey0                matlab.ui.control.NumericEditField
        Metodos            matlab.ui.container.ButtonGroup
        eEuler             matlab.ui.control.RadioButton
        eEulerM            matlab.ui.control.RadioButton
        eNRK2              matlab.ui.control.RadioButton
        eNRK4              matlab.ui.control.RadioButton
        Ode45              matlab.ui.control.RadioButton
        AtualizarButton    matlab.ui.control.Button
        RightPanel         matlab.ui.container.Panel
        Tabela             matlab.ui.control.Table
        FeitoporBrunoSousa2019103045RaquelMendes2012016451Label  matlab.ui.control.Label
        Grafico            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AtualizarButton
        function AtualizarButtonPushed(app, event)
           try
                
                syms y(t)
                strF = app.Ef.Value;
                
                f = @(t,y)eval(vectorize(strF));
                a = app.Ea.Value;
                b = app.Eb.Value;
                n = app.En.Value;
                y0 = app.Ey0.Value;
                
                %Criação dos erros
                erroBA = MException('inputs:BA','Introduza B > A');
                erroN = MException('inputs:n','Introduza N > 0');
                erroF = MException('inputs:f_ty','Introduza uma funcao do tipo y e t');
                
                try
                    app.Ef.BackgroundColor = 'w';
                    f(t,y);
                catch
                    app.Ef.BackgroundColor = 'r';
                    throw(erroF);  
                end
                
                if b < a || b==a
                    app.Ea.BackgroundColor = 'r';
                    throw(erroBA);
                else
                    app.Ea.BackgroundColor = 'w';
                end
                
                if n < 1
                    app.En.BackgroundColor = 'r';
                    throw(erroN);
                else
                    app.En.BackgroundColor = 'w';
                end
                              
                switch app.Metodos.SelectedObject.Text
                    
                    case 'Euler'
                        
                        yEuler = NEuler(f,a,b,n,y0);
                
                        sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);              
                        g =@(t) eval(vectorize(char(sExata)));
                        
                        h = (b-a)/n;
                        t = a:h:b;
                        yExata = g(t);
                        erroEuler = abs(yExata-yEuler);
                        
                        tabela = [t.',yExata.',yEuler.',erroEuler.'];
                        T = array2table(tabela);
                        app.Tabela.Data = T;                
                        
                        app.Tabela.ColumnName = {'t','Exata','Euler','erroEuler'};
                        
                        plot(app.Grafico,t,yExata,"-ro")
                        hold (app.Grafico,"on");
                        plot(app.Grafico,t,yEuler,"-g*")
                        hold (app.Grafico,"off");
                        grid (app.Grafico,"on");
                        legend(app.Grafico,'Exata','Euler');
   
                    case 'EulerM'
                        
                        yEulerM = NEulerM(f,a,b,n,y0);
                
                        sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);              
                        g =@(t) eval(vectorize(char(sExata)));
                        
                        h = (b-a)/n;
                        t = a:h:b;
                        yExata = g(t);
                        erroEulerM = abs(yExata-yEulerM);
                        
                        tabela = [t.',yExata.',yEulerM.',erroEulerM.'];
                        T = array2table(tabela);
                        app.Tabela.Data = T;
                        
                        app.Tabela.ColumnName = {'t','Exata','EulerM','erroEulerM'};
                        
                        plot(app.Grafico,t,yExata,"-ro")
                        hold (app.Grafico,"on");
                        plot(app.Grafico,t,yEulerM,"-g*")
                        hold (app.Grafico,"off");
                        grid (app.Grafico,"on");
                        legend(app.Grafico,'Exata','EulerM');
                        
                        
                    case 'NRK2'
                        
                        yNRK2 = NRK2(f,a,b,n,y0);
                
                        sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);              
                        g =@(t) eval(vectorize(char(sExata)));
                        
                        h = (b-a)/n;
                        t = a:h:b;
                        yExata = g(t);
                        erroNRK2 = abs(yExata-yNRK2);
                        
                        tabela = [t.',yExata.',yNRK2.',erroNRK2.'];
                        T = array2table(tabela);
                        app.Tabela.Data = T;
                        
                        app.Tabela.ColumnName = {'t','Exata','NRK2','erroNRK2'};
                        
                        plot(app.Grafico,t,yExata,"-ro")
                        hold (app.Grafico,"on");
                        plot(app.Grafico,t,yNRK2,"-y*")
                        hold (app.Grafico,"off");
                        grid (app.Grafico,"on");
                        legend(app.Grafico,'Exata','NRK2');
                        
                    case 'NRK4'
                        
                        yNRK4 = NRK4(f,a,b,n,y0);
                
                        sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);              
                        g =@(t) eval(vectorize(char(sExata)));
                        
                        h = (b-a)/n;
                        t = a:h:b;
                        yExata = g(t);
                        erroNRK4 = abs(yExata-yNRK4);
                        
                        tabela = [t.',yExata.',yNRK4.',erroNRK4.'];
                        T = array2table(tabela);
                        app.Tabela.Data = T;
                        
                        app.Tabela.ColumnName = {'t','Exata','NRK4','erroNRK4'};
                        
                        plot(app.Grafico,t,yExata,"-ro")
                        hold (app.Grafico,"on");
                        plot(app.Grafico,t,yNRK4,"-b*")
                        hold (app.Grafico,"off");
                        grid (app.Grafico,"on");
                        legend(app.Grafico,'Exata','NRK4');
                        
                    case 'Ode45'
                           
                        tspan = [a b];
                        [odeT,odeY] = ode45(f,tspan,y0);
                        
                        sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
                        g =@(t) eval(vectorize(char(sExata)));
                        
                        h = (b-a)/n;
                        t = a:h:b;
                        yExata = g(t);
                        
                        T = array2table([odeT,odeY],"VariableNames",{'t','y'});
                        app.Tabela.Data = T;
                        
                        app.Tabela.ColumnName = {'t','y'};
                        
                        plot(app.Grafico,t,yExata,"-ro")
                        hold (app.Grafico,"on");
                        plot(app.Grafico,odeT,odeY,"-g*")
                        hold (app.Grafico,"off");
                        grid (app.Grafico,"on");
                        legend(app.Grafico,'Exata','Ode45');
 
                end
              
            catch ME
                
                errordlg(ME.message,'modal');
                
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {424, 424};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {222, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosPanel = uipanel(app.LeftPanel);
            app.DadosPanel.Title = 'Dados ';
            app.ftyEditFieldLabel = uilabel(app.DadosPanel);
            app.ftyEditFieldLabel.Text = 'f =@(t,y)';
            app.Ef = uieditfield(app.DadosPanel, 'text');
            app.Ef.Value = 'y+exp(3*t)';
            app.aEditFieldLabel = uilabel(app.DadosPanel);
            app.aEditFieldLabel.Text = 'a';
            app.Ea = uieditfield(app.DadosPanel, 'numeric');
            app.bEditFieldLabel = uilabel(app.DadosPanel);
            app.bEditFieldLabel.Text = 'b';
            app.Eb = uieditfield(app.DadosPanel, 'numeric');
            app.nEditFieldLabel = uilabel(app.DadosPanel);
            app.nEditFieldLabel.Text = 'n';
            app.En = uieditfield(app.DadosPanel, 'numeric');
            app.y0EditFieldLabel = uilabel(app.DadosPanel);
            app.y0EditFieldLabel.Text = 'y0';
            app.Ey0 = uieditfield(app.DadosPanel, 'numeric');
            app.Metodos = uibuttongroup(app.LeftPanel);
            app.Metodos.Title = 'Metodo de calculo';
            app.eEuler = uiradiobutton(app.Metodos);
            app.eEuler.Text = 'Euler';
            app.eEuler.Value = true;
            app.eEulerM = uiradiobutton(app.Metodos);
            app.eEulerM.Text = 'EulerM';
            app.eNRK2 = uiradiobutton(app.Metodos);
            app.eNRK2.Text = 'NRK2';
            app.eNRK4 = uiradiobutton(app.Metodos);
            app.eNRK4.Text = 'NRK4';
            app.Ode45 = uiradiobutton(app.Metodos);
            app.Ode45.Text = 'Ode45';
            app.AtualizarButton = uibutton(app.LeftPanel, 'push');
            app.AtualizarButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.AtualizarButton.Text = 'Atualizar';
            app.RightPanel = uipanel(app.GridLayout);
            app.Tabela = uitable(app.RightPanel);
            app.Tabela.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Tabela.RowName = {};
            app.FeitoporBrunoSousa2019103045RaquelMendes2012016451Label = uilabel(app.RightPanel);
            app.FeitoporBrunoSousa2019103045RaquelMendes2012016451Label.Text = 'Feito por: Bruno Sousa - 2019103045  /  Raquel Mendes - 2012016451';
            app.Grafico = uiaxes(app.RightPanel);
            xlabel(app.Grafico, 't')
            ylabel(app.Grafico, 'y')
            zlabel(app.Grafico, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMPVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end